classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        SpecificrankEditField         matlab.ui.control.NumericEditField
        SpecificrankEditFieldLabel    matlab.ui.control.Label
        BrowserButton                 matlab.ui.control.Button
        PositionpathEditField         matlab.ui.control.EditField
        PositionpathEditFieldLabel    matlab.ui.control.Label
        SpecificgroupidclusternumberEditField  matlab.ui.control.NumericEditField
        SpecificgroupidclusternumberEditFieldLabel  matlab.ui.control.Label
        VisualizeButton               matlab.ui.control.Button
        SpecificclusteridEditField    matlab.ui.control.NumericEditField
        SpecificclusteridEditFieldLabel  matlab.ui.control.Label
        AlphaEditField                matlab.ui.control.NumericEditField
        AlphaEditFieldLabel           matlab.ui.control.Label
        AnchornumberEditField         matlab.ui.control.NumericEditField
        AnchornumberEditFieldLabel    matlab.ui.control.Label
        ClusternumbersEditField       matlab.ui.control.EditField
        ClusternumbersEditFieldLabel  matlab.ui.control.Label
        SavepathEditField             matlab.ui.control.EditField
        SavepathEditFieldLabel        matlab.ui.control.Label
        BrowserButton_2               matlab.ui.control.Button
        BrowserButton_3               matlab.ui.control.Button
        DatapathEditField             matlab.ui.control.EditField
        Label                         matlab.ui.control.Label
        StartButton                   matlab.ui.control.Button
        RightPanel                    matlab.ui.container.Panel
        UIAxes2                       matlab.ui.control.UIAxes
        UIAxes                        matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        data_path;
        save_path;
        neuron_position_path;
        data;
        neuron_position;
        lines;
        cluster_num_list;
        num_anchor;
        alpha;
        visualization_group_id;
        visualization_cluster_id;
        running_flag;
        labels_cell;
        unique_bases_cell;
        visualization_rank;

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.num_anchor = 1000;
            app.alpha = 0.1;
            app.cluster_num_list = [3, 4, 5];
            app.visualization_cluster_id = 1;
            app.running_flag = 0;
            app.visualization_group_id = 3;
            app.lines = '3, 4, 5';
            app.visualization_rank = 1;
            addpath('./LMVSC/');
            addpath('./LMVSC/measure/');
        end

        % Button pushed function: BrowserButton_3
        function BrowserButton_3Pushed(app, event)
                [file, path] = uigetfile({'*.mat','mat files'}, 'Please choose the mat file');
                if isequal(file,0)
                    figure(app.UIFigure);
                    return;
                end
                app.data_path = fullfile(path, file);
                app.DatapathEditField.Value = app.data_path;
                figure(app.UIFigure);
        end

        % Value changed function: DatapathEditField
        function DatapathEditFieldValueChanged(app, event)
            app.data_path = app.DatapathEditField.Value;
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            try
                raw_parts = strsplit(app.lines, {',', ' ', '\t'});
                raw_parts = raw_parts(~cellfun('isempty', raw_parts));
                nums = str2double(raw_parts); 
                app.cluster_num_list = nums;
                uialert(app.UIFigure, sprintf('The input numbers are: [%s]', app.lines), 'Cluster numbers');
            catch
                uialert(app.UIFigure, 'The inputs are not valid', 'Fatal');
                figure(app.UIFigure);
                return;
            end

            if isfile(app.data_path)
                app.data = load(app.data_path);
                uialert(app.UIFigure, 'Finish loading data.', 'Data load');
            else
                uialert(app.UIFigure, 'Please key a valid path.', 'Path error');
                figure(app.UIFigure);
                return;
            end

            if ~find(app.cluster_num_list == app.visualization_group_id) 
                uialert(app.UIFigure, 'Visualization group id is not in the cluster number list.', 'Cluster number error');
                figure(app.UIFigure);
                return;
            end

            X = app.data.whole_trace_ori;
            for t = 1:size(X,1)
                X(t,:) = X(t,:)./ norm(X(t,:),'fro');
            end
            X = double(X);

            cla(app.UIAxes);
            title(app.UIAxes, 'Running', 'FontSize', 14, 'Color', 'r');
            drawnow;
            
            tic;
            [~, H] = litekmeans(X, app.num_anchor,'MaxIter', 100, 'Replicates', 2);
            [app.labels_cell] = lmv_edit_multiple_k(X, app.cluster_num_list, H, app.alpha);
            elapsed_time = toc;
            fprintf('FRID consumes %.4f s.\n', elapsed_time);
            labels_cell = app.labels_cell;
            save(sprintf('%s/cluster_results.mat', app.save_path), 'labels_cell');

            tic;
            app.running_flag = 1;
            [app.unique_bases_cell] = extract_unique_bases_multiple_k(X', app.cluster_num_list, app.labels_cell, 10);  % RANK = 10
            elapsed_time = toc;
            fprintf('Bases extraction consumes %.4f s.\n', elapsed_time);

            cluster_to_plot = app.visualization_cluster_id;
            uniqueBases = app.unique_bases_cell{app.cluster_num_list == app.visualization_group_id};

            
            rank_to_plot = app.visualization_rank;
            basis_matrix_to_plot = uniqueBases{cluster_to_plot};

            plot(app.UIAxes, basis_matrix_to_plot(:, rank_to_plot), 'LineWidth', 1.5);
            title(app.UIAxes, ['Cluster ' num2str(cluster_to_plot) ' Unique vector ' num2str(rank_to_plot)], 'Color', 'black');
            xlabel(app.UIAxes, 'Time points');
            ylabel(app.UIAxes, 'Amplitude');
            grid(app.UIAxes, 'on');
            axis(app.UIAxes, 'tight');
            legend_labels = ['Base vector: ' num2str(rank_to_plot)];

            legend(app.UIAxes, legend_labels, 'Location', 'best', 'FontSize', 8);
            
            if isfile(app.neuron_position_path)
                app.neuron_position = load(app.neuron_position_path);
                visualization_labels = app.labels_cell{app.cluster_num_list == app.visualization_group_id};
                cla(app.UIAxes2);
                scatter(app.UIAxes2, ...
                    app.neuron_position.whole_center_2d_T(:, 1), ...
                    app.neuron_position.whole_center_2d_T(:, 2), ...
                    6, [.5 .5 .5], 'filled', 'MarkerFaceAlpha',0.4 ...
                    ); 
                hold(app.UIAxes2, 'on');
                scatter(app.UIAxes2, ...
                    app.neuron_position.whole_center_2d_T(visualization_labels == cluster_to_plot, 1), ...
                    app.neuron_position.whole_center_2d_T(visualization_labels == cluster_to_plot, 2), ...
                    6, 'r', 'filled' ...
                    );
            end
            % lines = app.NumbersTextArea.Value;
        end

        % Value changed function: SavepathEditField
        function SavepathEditFieldValueChanged(app, event)
            app.save_path = app.SavepathEditField.Value;
            
        end

        % Button pushed function: BrowserButton_2
        function BrowserButton_2Pushed(app, event)
            folder = uigetdir(pwd, 'Please choose the folder to save the results');
            if isequal(folder, 0)
                figure(app.UIFigure);
                return;
            end
            app.SavepathEditField.Value = folder;
            app.save_path = folder;
            figure(app.UIFigure);
        end

        % Value changed function: ClusternumbersEditField
        function ClusternumbersEditFieldValueChanged(app, event)
            app.lines = app.ClusternumbersEditField.Value;
        end

        % Value changed function: AlphaEditField
        function AlphaEditFieldValueChanged(app, event)
            app.alpha = app.AlphaEditField.Value;
            
        end

        % Value changed function: AnchornumberEditField
        function AnchornumberEditFieldValueChanged(app, event)

            app.num_anchor = app.AnchornumberEditField.Value;
            
        end

        % Value changed function: SpecificclusteridEditField
        function SpecificclusteridEditFieldValueChanged(app, event)
            app.visualization_cluster_id = app.SpecificclusteridEditField.Value;
            
        end

        % Button pushed function: VisualizeButton
        function VisualizeButtonPushed(app, event)
            if app.running_flag == 0
                uialert(app.UIFigure, 'This is no result.', 'Running error');
                figure(app.UIFigure);
                return;
            else
                if app.visualization_cluster_id > app.visualization_group_id
                    uialert(app.UIFigure, 'Cluster id can not exceed group id.', 'Running error');
                    figure(app.UIFigure);
                    return; 
                else
                    cla(app.UIAxes);
                    title(app.UIAxes, 'Running', 'FontSize', 14, 'Color', 'r');
                    drawnow;
                    
                    cluster_to_plot = app.visualization_cluster_id;
                    uniqueBases = app.unique_bases_cell{app.cluster_num_list == app.visualization_group_id};
                    rank_to_plot = app.visualization_rank;
                    basis_matrix_to_plot = uniqueBases{cluster_to_plot};
        
                    plot(app.UIAxes, basis_matrix_to_plot(:, rank_to_plot), 'LineWidth', 1.5);
                    title(app.UIAxes, ['Cluster ' num2str(cluster_to_plot) ' Unique vector ' num2str(rank_to_plot)], 'Color', 'black');
                    xlabel(app.UIAxes, 'Time points');
                    ylabel(app.UIAxes, 'Amplitude');
                    grid(app.UIAxes, 'on');
                    axis(app.UIAxes, 'tight');
                    legend_labels = ['Base vector: ' num2str(rank_to_plot)];
        
                    legend(app.UIAxes, legend_labels, 'Location', 'best', 'FontSize', 8);

                    if isfile(app.neuron_position_path)
                        app.neuron_position = load(app.neuron_position_path);
                        visualization_labels = app.labels_cell{app.cluster_num_list == app.visualization_group_id};
                        cla(app.UIAxes2);
                        scatter(app.UIAxes2, ...
                            app.neuron_position.whole_center_2d_T(:, 1), ...
                            app.neuron_position.whole_center_2d_T(:, 2), ...
                            6, [.5 .5 .5], 'filled', 'MarkerFaceAlpha',0.4 ...
                            ); 
                        hold(app.UIAxes2, 'on');
                        scatter(app.UIAxes2, ...
                            app.neuron_position.whole_center_2d_T(visualization_labels == cluster_to_plot, 1), ...
                            app.neuron_position.whole_center_2d_T(visualization_labels == cluster_to_plot, 2), ...
                            6, 'r', 'filled' ...
                            );
                    end
                end
            end
        end

        % Value changed function: SpecificgroupidclusternumberEditField
        function SpecificgroupidclusternumberEditFieldValueChanged(app, event)
            app.visualization_group_id = app.SpecificgroupidclusternumberEditField.Value;
            
        end

        % Value changed function: PositionpathEditField
        function PositionpathEditFieldValueChanged(app, event)
            app.neuron_position_path = app.PositionpathEditField.Value;
            
        end

        % Button pushed function: BrowserButton
        function BrowserButtonPushed(app, event)

            [file, path] = uigetfile({'*.mat','mat files'}, 'Please choose the mat file');
            if isequal(file,0)
                figure(app.UIFigure);
                return;
            end

            app.PositionpathEditField.Value = fullfile(path, file);
            app.neuron_position_path = fullfile(path, file);
            figure(app.UIFigure);
        end

        % Value changed function: SpecificrankEditField
        function SpecificrankEditFieldValueChanged(app, event)
            app.visualization_rank = app.SpecificrankEditField.Value;
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {707, 707};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {393, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.StartButton = uibutton(app.LeftPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.Label = uilabel(app.LeftPanel);
            app.Label.Text = 'Data path';
            app.DatapathEditField = uieditfield(app.LeftPanel, 'text');
            app.DatapathEditField.ValueChangedFcn = createCallbackFcn(app, @DatapathEditFieldValueChanged, true);
            app.BrowserButton_3 = uibutton(app.LeftPanel, 'push');
            app.BrowserButton_3.ButtonPushedFcn = createCallbackFcn(app, @BrowserButton_3Pushed, true);
            app.BrowserButton_3.Text = 'Browser';
            app.BrowserButton_2 = uibutton(app.LeftPanel, 'push');
            app.BrowserButton_2.ButtonPushedFcn = createCallbackFcn(app, @BrowserButton_2Pushed, true);
            app.BrowserButton_2.Text = 'Browser';
            app.SavepathEditFieldLabel = uilabel(app.LeftPanel);
            app.SavepathEditFieldLabel.Text = 'Save path';
            app.SavepathEditField = uieditfield(app.LeftPanel, 'text');
            app.SavepathEditField.ValueChangedFcn = createCallbackFcn(app, @SavepathEditFieldValueChanged, true);
            app.ClusternumbersEditFieldLabel = uilabel(app.LeftPanel);
            app.ClusternumbersEditFieldLabel.Text = 'Cluster number(s)';
            app.ClusternumbersEditField = uieditfield(app.LeftPanel, 'text');
            app.ClusternumbersEditField.ValueChangedFcn = createCallbackFcn(app, @ClusternumbersEditFieldValueChanged, true);
            app.ClusternumbersEditField.Value = '3, 4, 5';
            app.AnchornumberEditFieldLabel = uilabel(app.LeftPanel);
            app.AnchornumberEditFieldLabel.Text = 'Anchor number';
            app.AnchornumberEditField = uieditfield(app.LeftPanel, 'numeric');
            app.AnchornumberEditField.ValueChangedFcn = createCallbackFcn(app, @AnchornumberEditFieldValueChanged, true);
            app.AnchornumberEditField.Value = 1000;
            app.AlphaEditFieldLabel = uilabel(app.LeftPanel);
            app.AlphaEditFieldLabel.Text = 'Alpha';
            app.AlphaEditField = uieditfield(app.LeftPanel, 'numeric');
            app.AlphaEditField.ValueChangedFcn = createCallbackFcn(app, @AlphaEditFieldValueChanged, true);
            app.AlphaEditField.Value = 0.1;
            app.SpecificclusteridEditFieldLabel = uilabel(app.LeftPanel);
            app.SpecificclusteridEditFieldLabel.Text = 'Specific cluster id';
            app.SpecificclusteridEditField = uieditfield(app.LeftPanel, 'numeric');
            app.SpecificclusteridEditField.ValueChangedFcn = createCallbackFcn(app, @SpecificclusteridEditFieldValueChanged, true);
            app.SpecificclusteridEditField.Value = 1;
            app.VisualizeButton = uibutton(app.LeftPanel, 'push');
            app.VisualizeButton.ButtonPushedFcn = createCallbackFcn(app, @VisualizeButtonPushed, true);
            app.VisualizeButton.Text = 'Visualize';
            app.SpecificgroupidclusternumberEditFieldLabel = uilabel(app.LeftPanel);
            app.SpecificgroupidclusternumberEditFieldLabel.Text = 'Specific group id (cluster number)';
            app.SpecificgroupidclusternumberEditField = uieditfield(app.LeftPanel, 'numeric');
            app.SpecificgroupidclusternumberEditField.ValueChangedFcn = createCallbackFcn(app, @SpecificgroupidclusternumberEditFieldValueChanged, true);
            app.SpecificgroupidclusternumberEditField.Value = 3;
            app.PositionpathEditFieldLabel = uilabel(app.LeftPanel);
            app.PositionpathEditFieldLabel.Text = 'Position path';
            app.PositionpathEditField = uieditfield(app.LeftPanel, 'text');
            app.PositionpathEditField.ValueChangedFcn = createCallbackFcn(app, @PositionpathEditFieldValueChanged, true);
            app.BrowserButton = uibutton(app.LeftPanel, 'push');
            app.BrowserButton.ButtonPushedFcn = createCallbackFcn(app, @BrowserButtonPushed, true);
            app.BrowserButton.Text = 'Browser';
            app.SpecificrankEditFieldLabel = uilabel(app.LeftPanel);
            app.SpecificrankEditFieldLabel.Text = 'Specific rank';
            app.SpecificrankEditField = uieditfield(app.LeftPanel, 'numeric');
            app.SpecificrankEditField.ValueChangedFcn = createCallbackFcn(app, @SpecificrankEditFieldValueChanged, true);
            app.SpecificrankEditField.Value = 1;
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Visualization')
            xlabel(app.UIAxes, 'Time point')
            ylabel(app.UIAxes, 'Amplitude')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.RightPanel);
            title(app.UIAxes2, 'Spatial footprint')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end